classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EditField           matlab.ui.control.EditField
        BrowsefolderButton  matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
        FinishButton        matlab.ui.control.Button
        BrowseimagesButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseimagesButton
        function BrowseimagesButtonPushed(app, event)
            [fileNames, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.txt'}, 'Select image files', 'MultiSelect', 'on');
            if isequal(fileNames, 0)
                disp('No image files selected.')
                return;
            end
            if iscell(fileNames)
                numFiles= numel(fileNames);
            else
                numFiles = 1;
            end
                       
            imageDataArray = cell(1, numFiles+1);
            textDataArray = cell(1, numFiles+1);
            h = waitbar(0,'Please wait...');
            for i = 1:numFiles
                filePath = fullfile(path, fileNames{i});
                % Check file extension
                [~, ~, fileExt] = fileparts(filePath);
                if strcmpi(fileExt, '.txt')
                    % Read and store text data
                    fileData = fileread(filePath);
                    textDataArray{i} = fileData;
                else
                    % Read and store image data
                    imageDataArray{i} = imread(filePath);
                end
                waitbar(i / numFiles);
                newText = sprintf('%d / %d Files uploaded', i, numFiles);
                app.EditField.Value = newText;
            end
            imageDataArray = imageDataArray(1:end-1);
            save('imageData.mat', 'imageDataArray', '-v7.3');
            if ~isempty(textDataArray)
                textDataArray = textDataArray(1:end-1);
                save('textData.mat', 'textDataArray', '-v7.3');
            end
            close(h);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            % Get the path of the MATLAB file
            matlabFilePath = mfilename('fullpath');
            [folderPath, ~, ~] = fileparts(matlabFilePath);
            
            % Display a dialog box to choose a folder
            folderPath = uigetdir(folderPath, 'Select a folder');
            if isequal(folderPath, 0)
                disp('No folder selected.');
                return;
            end
            
            % Read image files from the "images" folder
            imageFolderPath = fullfile(folderPath, 'images');
            imageFileNames = dir(fullfile(imageFolderPath, '*.jpg'));
            numImages = numel(imageFileNames);

            % Read and save the .txt files from the "parameters" folder
            parametersFolderPath = fullfile(folderPath, 'parameters');
            txtFileNames = dir(fullfile(parametersFolderPath, '*.txt'));
            
            % Initialize a cell array to store the image data
            imageDataArray = cell(1, numImages+1);
            
            % Read and store the image data for each selected image
            h = waitbar(0,'Please wait...');
            for i = 1:numImages
                imagePath = fullfile(imageFolderPath, imageFileNames(i).name);
                imageDataArray{i} = imread(imagePath);
                waitbar(i / (numImages + numel(txtFileNames)));
                newText = sprintf('%d / %d Images uploaded', i, numImages);
                app.EditField.Value = newText;
            end
           
            % Get the current text in the edit field
            currentText = app.EditField.Value;
            
            % Save image data as 'imageData.mat' in the chosen folder
            imageDataArray = imageDataArray(1:end-1);
            save('imageData.mat', 'imageDataArray', '-v7.3');


            for j = 1:numel(txtFileNames)
                txtFilePath = fullfile(parametersFolderPath, txtFileNames(j).name);
                % txtData = fileread(txtFilePath);
                
                [~, txtFileName, ~] = fileparts(txtFilePath);
                
                % fid = fopen([txtFileName '.txt'], 'w');
                % fprintf(fid, '%s', txtData);
                % fclose(fid);
                copyfile(txtFilePath, [txtFileName '.txt']);
                            % Modify the text
                newText = sprintf('%d / %d Textfiles uploaded', j, numel(txtFileNames));
                waitbar((i+j )/ (numImages + numel(txtFileNames)));
                % Append the new text below the old text with a newline character
                updatedText = sprintf('%s \n\n %s', currentText, newText);
                app.EditField.Value = updatedText;
            end
            close(h);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseimagesButton = uibutton(app.UIFigure, 'push');
            app.BrowseimagesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseimagesButtonPushed, true);
            app.BrowseimagesButton.Text = 'Browse images';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = 'Browse folder';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end